classdef appn < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Option_StyleDropDown       matlab.ui.control.DropDown
        Option_StyleDropDownLabel  matlab.ui.control.Label
        MethodDropDown             matlab.ui.control.DropDown
        MethodDropDownLabel        matlab.ui.control.Label
        PRESSButton                matlab.ui.control.Button
        outputEditField            matlab.ui.control.NumericEditField
        outputEditFieldLabel       matlab.ui.control.Label
        correlationEditField       matlab.ui.control.NumericEditField
        correlationEditFieldLabel  matlab.ui.control.Label
        sigma2EditField            matlab.ui.control.NumericEditField
        sigma2EditFieldLabel       matlab.ui.control.Label
        sigma1EditField            matlab.ui.control.NumericEditField
        sigma1EditFieldLabel       matlab.ui.control.Label
        TEditField                 matlab.ui.control.NumericEditField
        TEditFieldLabel            matlab.ui.control.Label
        rEditField                 matlab.ui.control.NumericEditField
        rEditFieldLabel            matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        KEditFieldLabel            matlab.ui.control.Label
        S2EditField                matlab.ui.control.NumericEditField
        S2EditFieldLabel           matlab.ui.control.Label
        S1EditField                matlab.ui.control.NumericEditField
        S1EditFieldLabel           matlab.ui.control.Label
        APPLabel                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PRESSButton
        function PRESSButtonPushed(app, event)

            S10 = app.S1EditField.Value;
            S20 = app.S2EditField.Value;
            K = app.KEditField.Value;
            r = app.rEditField.Value;
            T = app.TEditField.Value;
            sigma1 = app.sigma1EditField.Value;
            sigma2 = app.sigma2EditField.Value;
            rho = app.correlationEditField.Value;
            
            N = 100;
            selected_method = app.MethodDropDown.Value;
            selected_style = app.Option_StyleDropDown.Value;


            function [price] = binomial_tree_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho)
                % This code uses the binomial tree method to price rainbow-style two-asset options by 
                % modeling stock price movements, calculating option values at each time step, and 
                % applying backward induction to determine the final option price.
                
                
                % Set initial parameters
                N = 5;             % Number of time steps
                dt = T/N;           % Time increment
                S1_0 = S10;       % Initial price of stock 1
                S2_0 = S20;          % Initial price of stock 2
                
                % Calculate up and down factors
                u1 = exp(sigma1*sqrt(dt));
                u2 = exp(sigma2*sqrt(dt));
                d1 = 1/u1;
                d2 = 1/u2;
                
                % Calculate stock prices at each time step
                i = (1:N+1).';
                S1_t = S1_0 * u1.^(i) .* d1.^(N-i);
                S2_t = S2_0 * u2.^(i) .* d2.^(N-i);
                
                % Initialize option value matrix
                V = zeros(N+1, N+1, N+1);
                
                % Set final option values at expiration
                V(:,:,N+1) = max(min(S1_t, S2_t.') - K, 0);
                
                % Calculate option values at earlier time steps using backward induction
                p_uu = 0.25*(1 + rho); %probability that both assets go up
                p_ud = 0.25*(1 - rho); %probability that 1st asset go up and other down
                p_du = 0.25*(1 - rho); %probability that 2nd asset go up and other down
                p_dd = 0.25*(1 + rho); %probability that both assets go up
                
                % Applying backward induction to determine option price
                for n = N-1:-1:0
                    for i = 1:n+1
                        for j = 1:n+1
                            V(i,j,n+1) = exp(-r*dt)*(p_uu*V(i+1,j+1,n+2) + p_ud*V(i+1,j,n+2) + p_du*V(i,j+1,n+2) + p_dd*V(i,j,n+2));
                        end
                    end
                end
                
                % The final option value is the value at time 0
                price = V(1, 1, 1);
            end

            
            function [price] = binomial_tree_digital(S10, S20, K, r, T, sigma1, sigma2, rho)
                % This code uses the binomial tree method to price digital-style two-asset options by 
                % modeling stock price movements, calculating option values at each time step, and 
                % applying backward induction to determine the final option price.
                
                % initial parameters as given
                N = 70;                    % Number of time steps
                dt = T/N;                   % Length of each time step in years
                S1_0 = S10;                  % Initial price of stock 1
                S2_0 = S20;                 % Initial price of stock 2
                
                u1 = exp(sigma1*sqrt(dt));  % Up factor for stock 1
                u2 = exp(sigma2*sqrt(dt));  % Up factor for stock 2
                d1 = 1/u1;                  % Down factor for stock 1
                d2 = 1/u2;                  % Down factor for stock 2
                
                % Calculating stock prices at each time step
                i = (0:N).';
                S1_v = S10 * u1.^(i-1) .* d1.^(N-i+1);  % stock price of asset 1 at various levels of up and down
                S2_v = S20 * u2.^(i-1) .* d2.^(N-i+1);  % stock price of asset 2 at various levels of up and down
                
                % Initialize option value matrix
                V = zeros(N+1, N+1, N+1);
                
                % Set final option values at expiration
                for i = 1:N+1
                    for j = 1:N+1
                        if S1_v(i) <= K && S2_v(j) <= K
                            V(i,j,N+1) = 1;  % Option value is 1 if both stock prices are below the strike price
                        else
                            V(i,j,N+1) = 0;  % Option value is 0 otherwise
                        end
                    end
                end
                
                % Calculate option values at earlier time steps using backward induction
                puu = 0.25*(1 + rho);  % Probability of up-up movement
                pud = 0.25*(1 - rho);  % Probability of up-down movement
                pdu = 0.25*(1 - rho);  % Probability of down-up movement
                pdd = 0.25*(1 + rho);  % Probability of down-down movement
                for n = N-1:-1:0
                    for i = 1:n+1
                        for j = 1:n+1
                            V(i,j,n+1) = exp(-r*dt)*(puu*V(i+1,j+1,n+2) + pud*V(i+1,j,n+2) + pdu*V(i,j+1,n+2) + pdd*V(i,j,n+2));
                            % Calculate option value at time step n using discounted expected value of future options
                        end
                    end
                end
                
                % The final option value is the value at time 0
                price = V(1,1,1) ;
            end

            
            function [price] = backward_finite_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho)
                % Two-Asset Rainbow Option Pricing using Backward Difference Method

                % Initialising required Parameters
                S1 = S10;    % Initial price of asset 1
                S2 = S20;   % Initial price of asset 2
                N1 = 100;           % Number of asset 1 steps
                N2 = 100;           % Number of asset 2 steps
                Nt = 100;           % Number of time steps
                dt = T/Nt;          % Time step size
                
                % Initialization
                dS1 = S1 / N1;
                dS2 = S2 / N2;
                S1_vec = linspace(0, 2*S1, N1+1)';
                S2_vec = linspace(0, 2*S2, N2+1)';
                
                V = zeros(N1+1, N2+1);  % option value matrix
                
                % New boundary conditions
                V(:, end) = max(min(S1_vec, S2_vec)-K, 0);  % S2 = 2*S2
                V(end, :) = max(min(S1_vec, S2_vec)-K, 0);  % S1 = 2*S1
                
                % Compute coefficient matrices
                a1 = 0.5 * dt * (sigma1 * S1_vec / dS1).^2;
                a2 = 0.5 * dt * (sigma2 * S2_vec / dS2).^2;
                b1 = 0.5 * dt * rho * sigma1 .* sigma2 .* S1_vec .* S2_vec / (dS1 * dS2);
                b2 = b1;
                c = 1 - (a1 + a2 + b1 + b2 + r * dt);
                
                % Create the tridiagonal matrix A1 using sparse matrix to save space
                diag1 = [a1(3:N1); 0] ;
                diag2 = c(2:N1);
                diag3 = b1(2:N1);
                A1 = spdiags([diag3 diag2 diag1], [-1 0 1], N1-1, N1-1);  
                
                % Create the tridiagonal matrix A2 using sparse matrix to save space
                diag1 = [a2(3:N2); 0] ;
                diag2 = c(2:N2);
                diag3 = b2(2:N2);
                A2 = spdiags([diag3 diag2 diag1], [-1 0 1], N2-1, N2-1);
                
                
                for t = Nt:-1:1
                    % Compute the option value at each time step
                    V_intermediate = A1 * V(2:N1, 2:N2) * A2';
                    % Apply the max-min style payoff
                    V_intermediate = max(min(S1_vec(2:N1), S2_vec(2:N2)) - K, V_intermediate);
                    % Update the option value matrix
                    V(2:N1, 2:N2) = V_intermediate;
                end
                
                % optionValue = V(N1+1, N2+1)
                price = interp2(S2_vec, S1_vec, V, S2, S1);         
            end


            function [price] = backward_finite_digital(S10, S20, K, r, T, sigma1, sigma2, rho)
                % Two-Asset Rainbow Option Pricing using Backward Difference Method

                % Initialising required Parameters
                S1 = S10;    % Initial price of asset 1
                S2 = S20;   % Initial price of asset 2
                N1 = 100;           % Number of asset 1 steps
                N2 = 100;           % Number of asset 2 steps
                Nt = 100;           % Number of time steps
                dt = T/Nt;          % Time step size
                
                % Initialization
                dS1 = S1 / N1;
                dS2 = S2 / N2;
                S1_vec = linspace(0, 2*S1, N1+1)';
                S2_vec = linspace(0, 2*S2, N2+1)';
                
                V = zeros(N1+1, N2+1);  % option value matrix
                
                % New boundary conditions
                V(:, end) = (min(S1_vec, S2_vec) <= K);  % S2 = 2*S2
                V(end, :) = (min(S1_vec, S2_vec) <= K);  % S1 = 2*S1
                
                % Compute coefficient matrices
                a1 = dt * (sigma1 * S1_vec / dS1).^2;
                a2 = dt * (sigma2 * S2_vec / dS2).^2;
                b1 = dt * rho * sigma1 .* sigma2 .* S1_vec .* S2_vec / (dS1 * dS2);
                b2 = b1;
                c = 1 + r * dt;
                
                % Create the tridiagonal matrix A1
                diag1 = [a1(3:N1); 0];
                diag2 = -c * ones(N1-1, 1);
                diag3 = b1(2:N1);
                A1 = spdiags([diag3 diag2 diag1], [-1 0 1], N1-1, N1-1);
                
                % Create the tridiagonal matrix A2
                diag1 = [a2(3:N2); 0];
                diag2 = -c*ones(N2-1, 1);
                diag3 = b2(2:N2);
                A2 = spdiags([diag3 diag2 diag1], [-1 0 1], N2-1, N2-1);
                
                % Main loop for time steps
                for t = Nt:-1:1
                    % Compute the option value at each time step
                    V_intermediate = A1 * V(2:N1, 2:N2);
                    % Apply the digital-style payoff
                    V_intermediate = max(V_intermediate, max(S1_vec(2:N1), S2_vec(2:N2)) <= K);
                
                    % Update the option value matrix
                    V(2:N1, 2:N2) = V_intermediate;
                end
                
                % optionValue = V(N1+1, N2+1);
                price= interp2(S2_vec, S1_vec, V, S2, S1) ;
            end


            function [price] = monte_carlo_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho)
                % Monte Carlo Simulation for Two-Asset Option Pricing
                % It generates multiple asset price by taking account in volatality and
                % correlation, whose mean should converge to actual value of option
                
                % Initialising required parameters
                d = 2; % Number of assets
                N = 10000; % Number of simulations
                
                % Cholesky decomposition
                sigma_sq = [sigma1^2 rho*sigma1*sigma2; rho*sigma1*sigma2 sigma2^2];
                L = chol(sigma_sq, 'lower');
                
                % Simulating stock prices by taking account in volatality by generating random numbers normally
                Z = randn(N, d);
                S = [S10*exp((r-0.5*sigma1^2)*T + sqrt(T)*Z(:,1)), S20*exp((r-0.5*sigma2^2)*T + sqrt(T)*(L*Z(:,1:2)')')];
                
                % Computing option payoffs
                payoff = max(min(S(:,1), S(:,2)) - K, 0);
                
                % Computing option price
                price = exp(-r*T)*mean(payoff) ;
            end


            function [price] = monte_carlo_digital(S10, S20, K, r, T, sigma1, sigma2, rho)
                % Monte Carlo Method for Pricing Two-Asset Digital Option
                
                % Parameters
                S1_0 = S10; % Initial stock price of asset 1
                S2_0 = S20; % Initial stock price of asset 2
                num_simulations = 1000; % Number of simulations


                % Cholesky decomposition
                sigma_sq = [sigma1^2 rho*sigma1*sigma2; rho*sigma1*sigma2 sigma2^2];
                L = chol(sigma_sq, 'lower');
                
                
                % Simulating stock prices by taking account in volatality by generating random numbers normally
                Z = randn(num_simulations, 2);
                S = [S1_0*exp((r-0.5*sigma1^2)*T + sqrt(T)*Z(:,1)), S2_0*exp((r-0.5*sigma2^2)*T + sqrt(T)*(L*Z(:,1:2)')')];
                
                
                % Computing option payoffs 
                payoff = (S(:,1) <= K & S(:,2) <= K);
                
                % Computing option price
                price = exp(-r * T) * mean(payoff) ;
            end

            
            function [price] = CN_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho)
                % The Crank-Nicolson method to price two-asset rainbow-style options. 
                % It combines implicit and explicit finite difference methods to efficiently approximate the option value,
                % providing accurate results with enhanced stability and convergence properties.
                
                % Initialising required Parameters
                S1 = S10;    % Initial price of asset 1
                S2 = S20;   % Initial price of asset 2
                N1 = 100;           % Number of asset 1 steps
                N2 = 100;           % Number of asset 2 steps
                Nt = 100;           % Number of time steps
                dt = T/Nt;          % Time step size
                
                
                % Initialization
                dS1 = S1 / N1;
                dS2 = S2 / N2;
                S1_vec = linspace(0, 2*S1, N1+1)';
                S2_vec = linspace(0, 2*S2, N2+1)';
                
                V = zeros(N1+1, N2+1);  % option value matrix
                
                % New boundary conditions
                V(:, end) = max(min(S1_vec, S2_vec) - K, 0);  % S2 = 2*S2
                V(end, :) = max(min(S1_vec, S2_vec) - K, 0);  % S1 = 2*S1
                
                % Compute coefficient matrices
                a1 = 0.25 * dt * (sigma1 * S1_vec / dS1).^2;
                a2 = 0.25 * dt * (sigma2 * S2_vec / dS2).^2;
                b1 = 0.25 * dt * rho * sigma1 .* sigma2 .* S1_vec .* S2_vec / (dS1 * dS2);
                b2 = b1;
                c = 1 - (a1 + a2 + b1 + b2 + 0.5 * r * dt);
                
                % Create the tridiagonal matrix A1
                diag1 = [a1(3:N1); 0] ; 
                diag2 = c(2:N1);
                diag3 = b1(2:N1);
                A1 = spdiags([diag3 diag2 diag1], [-1 0 1], N1-1, N1-1);
                
                % Create the tridiagonal matrix A2
                diag1 = [a2(3:N2); 0] ; 
                diag2 = c(2:N2);
                diag3 = b2(2:N2);
                A2 = spdiags([diag3 diag2 diag1], [-1 0 1], N2-1, N2-1);
                
                % Create the identity matrices
                I1 = speye(N1-1);
                I2 = speye(N2-1);
                
                % Create the matrix M1
                M1 = (I1 - 0.5 * dt * A1);
                
                % Create the matrix M2
                M2 = (I2 - 0.5 * dt * A2);
                
                % Main loop for time steps
                for t = 1:Nt
                    % Compute the option value at each time step
                    V_intermediate = M1 \ (M2 * V(2:N1, 2:N2));
                    % Apply the max-min style payoff
                    V_intermediate = max(min(S1_vec(2:N1), S2_vec(2:N2)) - K, V_intermediate);
                    % Update the option value matrix
                    V(2:N1, 2:N2) = V_intermediate;
                end
                
                % optionValue = V(N1+1, N2+1);
                price = interp2(S2_vec, S1_vec, V, S2, S1) ;
            end


            function [price] = CN_digital(S10, S20, K, r, T, sigma1, sigma2, rho)
                % The Crank-Nicolson method to price two-asset digital-style options. 
                % It combines implicit and explicit finite difference methods to efficiently approximate the option value,
                % providing accurate results with enhanced stability and convergence properties.
                
                % Initialising required Parameters
                S1 = S10;    % Initial price of asset 1
                S2 = S20;   % Initial price of asset 2
                N1 = 100;           % Number of asset 1 steps
                N2 = 100;           % Number of asset 2 steps
                Nt = 100;           % Number of time steps
                dt = T/Nt;          % Time step size


                % Initialization
                dS1 = S1 / N1;
                dS2 = S2 / N2;
                S1_vec = linspace(0, 2*S1, N1+1)';
                S2_vec = linspace(0, 2*S2, N2+1)';
                
                V = zeros(N1+1, N2+1);  % option value matrix
                
                % New boundary conditions
                V(:, end) = (max(S1_vec, S2_vec) <= K);  % S2 = 2*S2
                V(end, :) = (max(S1_vec, S2_vec) <= K);  % S1 = 2*S1
                
                % Compute coefficient matrices
                a1 = 0.25 * dt * (sigma1 * S1_vec / dS1).^2;
                a2 = 0.25 * dt * (sigma2 * S2_vec / dS2).^2;
                b1 = 0.25 * dt * rho * sigma1 .* sigma2 .* S1_vec .* S2_vec / (dS1 * dS2);
                b2 = b1;
                c = 1 - (a1 + a2 + b1 + b2 + 0.5 * r * dt);
                
                
                % Create the tridiagonal matrix A1
                diag1 = [a1(3:N1); 0] ; 
                diag2 = c(2:N1);
                diag3 = b1(2:N1);
                A1 = spdiags([diag3 diag2 diag1], [-1 0 1], N1-1, N1-1);
                
                % Create the tridiagonal matrix A2
                diag1 = [a2(3:N2); 0] ; 
                diag2 = c(2:N2);
                diag3 = b2(2:N2);
                A2 = spdiags([diag3 diag2 diag1], [-1 0 1], N2-1, N2-1);
                
                % Create the identity matrices
                I1 = speye(N1-1);
                I2 = speye(N2-1);
                
                % Create the matrix M1
                M1 = (I1 - 0.5 * dt * A1);
                
                % Create the matrix M2
                M2 = (I2 - 0.5 * dt * A2);
                
                % Main loop for time steps
                for t = 1:Nt
                    % Compute the option value at each time step
                    V_intermediate = M1 \ (M2 * V(2:N1, 2:N2));
                    % Apply the digital-style payoff
                    V_intermediate = max(max(S1_vec(2:N1), S2_vec(2:N2)) <= K, V_intermediate);
                    % Update the option value matrix
                    V(2:N1, 2:N2) = V_intermediate;
                end
                
                % optionValue = V(N1+1, N2+1)
                price = interp2(S2_vec, S1_vec, V, S2, S1);
            end


            % Calculate the option price based on the selected method
            switch selected_method
                case 'Binomial Tree Method'
                    if (selected_style=="Rainbow Style")
                        price = binomial_tree_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho);
                    else
                        price = binomial_tree_digital(S10, S20, K, r, T, sigma1, sigma2, rho);
                    end
                case 'Backward Finite Difference Method'
                    if (selected_style=="Rainbow Style")
                        price = backward_finite_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho);
                    else
                        price = backward_finite_digital(S10, S20, K, r, T, sigma1, sigma2, rho);
                    end
                case 'Monte Carlo Method'
                    if (selected_style=="Rainbow Style")
                        price = monte_carlo_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho);
                    else
                        price = monte_carlo_digital(S10, S20, K, r, T, sigma1, sigma2, rho);
                    end
                case 'Crank Nicolson Method'
                    if (selected_style=="Rainbow Style")
                        price = CN_rainbow(S10, S20, K, r, T, sigma1, sigma2, rho);
                    else
                        price = CN_digital(S10, S20, K, r, T, sigma1, sigma2, rho);
                    end
                otherwise
                    error('Invalid option selected');
            end
            % Update the output edit field with the calculated price
            app.outputEditField.Value = price;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.APPLabel = uilabel(app.UIFigure);
            app.APPLabel.Text = 'APP';
            app.S1EditFieldLabel = uilabel(app.UIFigure);
            app.S1EditFieldLabel.Text = 'S1';
            app.S1EditField = uieditfield(app.UIFigure, 'numeric');
            app.S1EditField.Value = 95;
            app.S2EditFieldLabel = uilabel(app.UIFigure);
            app.S2EditFieldLabel.Text = 'S2';
            app.S2EditField = uieditfield(app.UIFigure, 'numeric');
            app.S2EditField.Value = 105;
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.Value = 100;
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditField.Value = 0.05;
            app.TEditFieldLabel = uilabel(app.UIFigure);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.UIFigure, 'numeric');
            app.TEditField.Value = 1;
            app.sigma1EditFieldLabel = uilabel(app.UIFigure);
            app.sigma1EditFieldLabel.Text = 'sigma1';
            app.sigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma1EditField.Value = 0.2;
            app.sigma2EditFieldLabel = uilabel(app.UIFigure);
            app.sigma2EditFieldLabel.Text = 'sigma2';
            app.sigma2EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma2EditField.Value = 0.2;
            app.correlationEditFieldLabel = uilabel(app.UIFigure);
            app.correlationEditFieldLabel.Text = 'correlation';
            app.correlationEditField = uieditfield(app.UIFigure, 'numeric');
            app.correlationEditField.Value = 0.1;
            app.outputEditFieldLabel = uilabel(app.UIFigure);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.UIFigure, 'numeric');
            app.PRESSButton = uibutton(app.UIFigure, 'push');
            app.PRESSButton.ButtonPushedFcn = createCallbackFcn(app, @PRESSButtonPushed, true);
            app.PRESSButton.Text = 'PRESS';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method ';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Binomial Tree Method', 'Monte Carlo Method', 'Backward Finite Difference Method', 'Crank Nicolson Method'};
            app.MethodDropDown.Value = 'Binomial Tree Method';
            app.Option_StyleDropDownLabel = uilabel(app.UIFigure);
            app.Option_StyleDropDownLabel.Text = 'Option_Style';
            app.Option_StyleDropDown = uidropdown(app.UIFigure);
            app.Option_StyleDropDown.Items = {'Rainbow Style', 'Digital Style'};
            app.Option_StyleDropDown.Value = 'Rainbow Style';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appn

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end